classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FormatHelpButton           matlab.ui.control.Button
        TheveninTerminalsPanel     matlab.ui.container.Panel
        NegativeTheveninTerminal   matlab.ui.control.EditField
        TerminalLabel_2            matlab.ui.control.Label
        PositiveTheveninTerminal   matlab.ui.control.EditField
        TerminalLabel              matlab.ui.control.Label
        ClearAllButton             matlab.ui.control.Button
        SolveButton                matlab.ui.control.Button
        ResultDisplayArea          matlab.ui.container.Panel
        Result                     matlab.ui.control.TextArea
        NetlistPanel               matlab.ui.container.Panel
        NetlistInputPanel          matlab.ui.control.TextArea
        TheveninSolverPanel        matlab.ui.container.Panel
        FrequencyHzEditField       matlab.ui.control.EditField
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        ChooseTypeButtonGroup      matlab.ui.container.ButtonGroup
        ACAnalysisButton           matlab.ui.control.RadioButton
        DCAnalysisButton           matlab.ui.control.RadioButton
    end

        properties (Access = private)
            analysis    % struct to hold analysis type/omega
            circuit     % struct to hold parsed circuit
        end
    methods (Access = private)
        
   function circuit = parseNetlist(app, netlistLines)
    % --- 1. Initializing circuit components ---
    circuit.resistors = [];
    circuit.capacitors = [];
    circuit.inductors = [];
    circuit.currents = [];
    circuit.vsources = [];
    
    % New structures for Advanced MNA Mapping
    circuit.v_names = {};         % Master list for A-matrix row indexing
    circuit.inductor_names = {};   % For DC-Short tracking
    circuit.vcvs = [];            % Numeric matrix for voltage-controlled
    circuit.vccs = [];            % Numeric matrix for voltage-controlled
    circuit.ccvs_data = {};       % Cell array for string-based control
    circuit.cccs_data = {};       % Cell array for string-based control
    
    % --- 2. Processing Each Line ---
    for i = 1:length(netlistLines)
        line = strtrim(netlistLines{i});
        if isempty(line) || startsWith(line,'%') || startsWith(line,'#')
            continue;
        end
        
        parts = strsplit(line);
        name = upper(parts{1});    % Normalize name (e.g., 'V1', 'VCVS_LOAD')
        type_check = lower(name);  % For case-insensitive type checking
        
        % --- 3. Component Switching (Longest Names First) ---
        if startsWith(type_check, 'vcvs')
            out_p = parseNode(parts{2});
            out_n = parseNode(parts{3});
            ctrl_p = parseNode(parts{4});
            ctrl_n = parseNode(parts{5});
            gain = parseValue(parts{6});
            circuit.vcvs = [circuit.vcvs; out_p out_n ctrl_p ctrl_n gain];
            circuit.v_names{end+1} = name; % Adds a row to MNA matrix

        elseif startsWith(type_check, 'vccs')
            out_p = parseNode(parts{2});
            out_n = parseNode(parts{3});
            ctrl_p = parseNode(parts{4});
            ctrl_n = parseNode(parts{5});
            gm = parseValue(parts{6});
            circuit.vccs = [circuit.vccs; out_p out_n ctrl_p ctrl_n gm];

        elseif startsWith(type_check, 'ccvs')
            out_p = parseNode(parts{2});
            out_n = parseNode(parts{3});
            ctrl_src = upper(parts{4}); % Name of the controlling V-branch
            gain = parseValue(parts{5});
            circuit.ccvs_data{end+1} = {out_p, out_n, ctrl_src, gain};
            circuit.v_names{end+1} = name; % Adds a row to MNA matrix

        elseif startsWith(type_check, 'cccs')
            out_p = parseNode(parts{2});
            out_n = parseNode(parts{3});
            ctrl_src = upper(parts{4}); % Name of the controlling V-branch
            gain = parseValue(parts{5});
            circuit.cccs_data{end+1} = {out_p, out_n, ctrl_src, gain};

        elseif startsWith(type_check, 'v')
            n1 = parseNode(parts{2});
            n2 = parseNode(parts{3});
            mag = parseValue(parts{4});
            phase = 0;
            if length(parts) >= 5, phase = parseValue(parts{5}); end
            circuit.vsources = [circuit.vsources; n1 n2 mag phase];
            circuit.v_names{end+1} = name; % Adds a row to MNA matrix

        elseif startsWith(type_check, 'r')
            n1 = parseNode(parts{2}); n2 = parseNode(parts{3});
            R = parseValue(parts{4});
            circuit.resistors = [circuit.resistors; n1 n2 R];

        elseif startsWith(type_check, 'c')
            n1 = parseNode(parts{2}); n2 = parseNode(parts{3});
            C = parseValue(parts{4});
            circuit.capacitors = [circuit.capacitors; n1 n2 C];

        elseif startsWith(type_check, 'l')
            n1 = parseNode(parts{2}); n2 = parseNode(parts{3});
            L = parseValue(parts{4});
            circuit.inductors = [circuit.inductors; n1 n2 L];
            circuit.inductor_names{end+1} = name;

        elseif startsWith(type_check, 'i')
            n1 = parseNode(parts{2}); n2 = parseNode(parts{3});
            mag = parseValue(parts{4});
            phase = 0;
            if length(parts) >= 5, phase = parseValue(parts{5}); end
            circuit.currents = [circuit.currents; n1 n2 mag phase];

        else
            warning('Unknown element type: %s', name);
        end
    end

    % --- 4. Node Calculation (Safe for Cells & Matrices) ---
    % --- 4. Node Calculation (Safe for Cells & Matrices) ---
    all_n = [0]; % Initialize with ground
    
    % Use (:) to force any extracted nodes into a single column
    if ~isempty(circuit.resistors), all_n = [all_n; circuit.resistors(:,1); circuit.resistors(:,2)]; end
    if ~isempty(circuit.capacitors), all_n = [all_n; circuit.capacitors(:,1); circuit.capacitors(:,2)]; end
    if ~isempty(circuit.inductors), all_n = [all_n; circuit.inductors(:,1); circuit.inductors(:,2)]; end
    if ~isempty(circuit.currents), all_n = [all_n; circuit.currents(:,1); circuit.currents(:,2)]; end
    if ~isempty(circuit.vsources), all_n = [all_n; circuit.vsources(:,1); circuit.vsources(:,2)]; end
    
    % For Controlled sources, grab all 4 node columns and flatten them
    if ~isempty(circuit.vccs)
        nodes = circuit.vccs(:,1:4);
        all_n = [all_n; nodes(:)]; 
    end
    if ~isempty(circuit.vcvs)
        nodes = circuit.vcvs(:,1:4);
        all_n = [all_n; nodes(:)];
    end
    
    % Extract nodes from Cell Arrays
    if ~isempty(circuit.ccvs_data)
        for j = 1:length(circuit.ccvs_data)
            all_n = [all_n; circuit.ccvs_data{j}{1}; circuit.ccvs_data{j}{2}];
        end
    end
    if ~isempty(circuit.cccs_data)
        for j = 1:length(circuit.cccs_data)
            all_n = [all_n; circuit.cccs_data{j}{1}; circuit.cccs_data{j}{2}];
        end
    end
    
    circuit.N = max(all_n);
   end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrequencyHzEditField.Enable='off';
        end

        % Button pushed function: FormatHelpButton
        function FormatHelpButtonPushed(app, event)
            helpText = sprintf(['NETLIST FORMAT (one element per line):\n\n' ...
                'RESISTORS:  r <node1> <node2> <resistance>\n' ...
                'Example: r 1 2 100\n\n' ...
                'CAPACITORS: c <node1> <node2> <capacitance>\n' ...
                'Example: c 2 0 1e-6\n\n' ...
                'INDUCTORS:  l <node1> <node2> <inductance>\n' ...
                'Example: l 3 0 0.01\n\n' ...
                'CURRENT SRC: i <from> <to> <mag> <phase>\n' ...
                'Example: i 0 1 5 0\n\n' ...
                'VOLTAGE SRC: v <+node> <-node> <mag> <phase>\n' ...
                'Example: v 1 0 10 0\n\n' ...
                'VCCS: vccs <out+> <out-> <ctrl+> <ctrl-> <gm>\n' ...
                'Example: vccs 2 0 1 0 0.01\n\n' ...
                'VCVS: vcvs <out+> <out-> <ctrl+> <ctrl-> <gain>\n' ...
                'Example: vcvs 3 0 1 0 2\n\n' ...
                'CCCS: cccs <out+> <out-> <vsrc name> <gain>\n' ...
                'Example: cccs 2 0 1 0.5\n\n' ...
                'CCVS: ccvs <out+> <out-> <vsrc name> <gain>\n' ...
                'Example: ccvs 3 0 1 10\n\n' ...
                'NOTE: Node 0 is GROUND\n' ...
                'Phase in degrees (for AC only)\n' ...
                'N.B: Use dummy voltages for CCCS and CCVS if ' ...
                'the goal is to use current accros a branch\n' ...
                'that has no element in it.']);
            
            uialert(app.UIFigure, helpText, 'Netlist Format Help', 'Icon', 'info');
        end

        % Selection changed function: ChooseTypeButtonGroup
        function ChooseTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseTypeButtonGroup.SelectedObject;
            if selectedButton==app.ACAnalysisButton
                app.FrequencyHzEditField.Enable='On';
            else
                app.FrequencyHzEditField.Enable='Off';
            end

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
           try
            app.Result.Value='Solving...';
            drawnow;
            if app.ChooseTypeButtonGroup.SelectedObject==app.ACAnalysisButton
                app.analysis.type='AC';
                app.analysis.omega=2*pi*parseValue(app.FrequencyHzEditField.Value);
            else
                app.analysis.type='DC';
                app.analysis.omega=0;
            end
            %Nelist Input Handling Section
            app.circuit=parseNetlist(app,app.NetlistInputPanel.Value);

            n1 = parseNode(app.PositiveTheveninTerminal.Value);
            n2 = parseNode(app.NegativeTheveninTerminal.Value);

            %Calculating the Thevenin Equivalent

            [Vth,Zth]=thevenin_finder(app.circuit,n1,n2,app.analysis);

            % Showing Result
            resultText='';
            resultText=[resultText sprintf("========Result========\n")];

            if strcmp(app.analysis.type,'AC')
                resultText=[resultText sprintf('Vth = %.3f ∠ %.2f° V\n',abs(Vth),rad2deg(angle(Vth)))];
                resultText=[resultText sprintf('Zth = %.4f + %.4f j Ω\n',real(Zth),imag(Zth))];
                resultText=[resultText sprintf('|Zth|=%.4f Ω\n',abs(Zth))];
                resultText=[resultText sprintf('Phase of Zth = %.4f°',rad2deg(angle(Zth)))];
                
            else
                 resultText=[resultText sprintf('Vth = %.4f V\n', real(Vth))];
                 resultText=[resultText sprintf('Rth = %.4f Ω\n', real(Zth))];
            end
            app.Result.Value=resultText;
           catch ME
               uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
               app.Result.Value=['ERROR :',ME.message];
           end


        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.NetlistInputPanel.Value='';
            app.Result.Value='';
            app.PositiveTheveninTerminal.Value='';
            app.NegativeTheveninTerminal.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TheveninSolverPanel = uipanel(app.UIFigure);
            app.TheveninSolverPanel.Title = 'Thevenin Solver';
            app.ChooseTypeButtonGroup = uibuttongroup(app.TheveninSolverPanel);
            app.ChooseTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseTypeButtonGroupSelectionChanged, true);
            app.ChooseTypeButtonGroup.Title = 'Choose Type';
            app.DCAnalysisButton = uiradiobutton(app.ChooseTypeButtonGroup);
            app.DCAnalysisButton.Text = 'DC Analysis';
            app.DCAnalysisButton.Value = true;
            app.ACAnalysisButton = uiradiobutton(app.ChooseTypeButtonGroup);
            app.ACAnalysisButton.Text = 'AC Analysis';
            app.FrequencyHzEditFieldLabel = uilabel(app.TheveninSolverPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TheveninSolverPanel, 'text');
            app.NetlistPanel = uipanel(app.UIFigure);
            app.NetlistPanel.Title = 'Netlist Input ';
            app.NetlistInputPanel = uitextarea(app.NetlistPanel);
            app.ResultDisplayArea = uipanel(app.UIFigure);
            app.ResultDisplayArea.Title = 'Result';
            app.Result = uitextarea(app.ResultDisplayArea);
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve ';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.TheveninTerminalsPanel = uipanel(app.UIFigure);
            app.TheveninTerminalsPanel.Title = 'Thevenin Terminals';
            app.TerminalLabel = uilabel(app.TheveninTerminalsPanel);
            app.TerminalLabel.Text = '(+) Terminal';
            app.PositiveTheveninTerminal = uieditfield(app.TheveninTerminalsPanel, 'text');
            app.TerminalLabel_2 = uilabel(app.TheveninTerminalsPanel);
            app.TerminalLabel_2.Text = '(-) Terminal';
            app.NegativeTheveninTerminal = uieditfield(app.TheveninTerminalsPanel, 'text');
            app.FormatHelpButton = uibutton(app.UIFigure, 'push');
            app.FormatHelpButton.ButtonPushedFcn = createCallbackFcn(app, @FormatHelpButtonPushed, true);
            app.FormatHelpButton.Text = 'Format Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end